classdef piano < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        SampleRatesPanel                matlab.ui.container.Panel
        GridLayout_3                    matlab.ui.container.GridLayout
        Base_Label                      matlab.ui.control.Label
        FrameSIze_EditField             matlab.ui.control.NumericEditField
        FrameSIzeLabel                  matlab.ui.control.Label
        Base_EditField                  matlab.ui.control.NumericEditField
        MotionCapture_EditField         matlab.ui.control.NumericEditField
        BloodOxygention_EditField       matlab.ui.control.NumericEditField
        MotionCaptureLabel              matlab.ui.control.Label
        BloodOxygention_Label           matlab.ui.control.Label
        sEMG_Label                      matlab.ui.control.Label
        sEMG_EditField                  matlab.ui.control.NumericEditField
        MotionCaptureMarkerTolerancePanel  matlab.ui.container.Panel
        GridLayout_2                    matlab.ui.container.GridLayout
        MotionCaptureTol_Z_EditField    matlab.ui.control.NumericEditField
        MotionCaptureTol_Y_EditField    matlab.ui.control.NumericEditField
        MotionCaptureTol_X_EditField    matlab.ui.control.NumericEditField
        ZLabel_2                        matlab.ui.control.Label
        YLabel_2                        matlab.ui.control.Label
        XLabel_2                        matlab.ui.control.Label
        MotionCaptureMarkerCoordinatesPanel  matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        MotionCapture_Z_EditField       matlab.ui.control.NumericEditField
        MotionCapture_Y_EditField       matlab.ui.control.NumericEditField
        ZLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        MotionCapture_X_EditField       matlab.ui.control.NumericEditField
        SynchronizeButton               matlab.ui.control.Button
        Label_MotionCapture             matlab.ui.control.Label
        Label_sEMG                      matlab.ui.control.Label
        Label_MIDI                      matlab.ui.control.Label
        Label_BloodOxygention           matlab.ui.control.Label
        MotionCapturematButton          matlab.ui.control.Button
        sEMGsensortxtButton             matlab.ui.control.Button
        MIDIcapturewavButton            matlab.ui.control.Button
        BloodOxygentionSensormatButton  matlab.ui.control.Button
        renderTab                       matlab.ui.container.Tab
        SETTINGSButtonGroup             matlab.ui.container.ButtonGroup
        plot_options_channel1_sEMG_color  matlab.ui.control.Button
        plot_options_MIDI_color         matlab.ui.control.Button
        plot_options_channel1_BloodOxygention_Color  matlab.ui.control.Button
        plot_options_channel2_sEMG_color  matlab.ui.control.Button
        plot_options_channel2_BloodOxygention_color  matlab.ui.control.Button
        Plot_options_plot_grid_2        matlab.ui.control.CheckBox
        Plot_options_plot_grid          matlab.ui.control.CheckBox
        Plot_options_plot_maxY          matlab.ui.control.NumericEditField
        yLabel                          matlab.ui.control.Label
        Plot_options_plot_minY          matlab.ui.control.NumericEditField
        Plot_options_plot_minX          matlab.ui.control.NumericEditField
        xLabel                          matlab.ui.control.Label
        Plot_options_plot_maxX          matlab.ui.control.NumericEditField
        PLOTLabel                       matlab.ui.control.Label
        plot_options_channel2_BloodOxygention  matlab.ui.control.CheckBox
        plot_options_channel2_sEMG      matlab.ui.control.CheckBox
        Channel1Label                   matlab.ui.control.Label
        Channel2Label                   matlab.ui.control.Label
        plot_options_channel1_BloodOxygention  matlab.ui.control.CheckBox
        plot_options_channel1_sEMG      matlab.ui.control.CheckBox
        plot_options_MIDI               matlab.ui.control.CheckBox
        graph                           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %FILES
        SynchronisationSignalFile 
        BloodOxygentionSensorFile
        sEMGsensorFile 
        MotionCaptureFile
    end    

    properties (Access = private)
        %MARKER DATA
        markerLocation; 
        markerTolerance; 
        
        %SAMPLE RATES
        sEMG_sample_rate;
        BloodOxygention_sample_rate;
        MotionCapture_sample_rate;
        base_sample_rate;
        frame_size;

    end

    properties(Access = public)
        %ploting
        setOfPlots         

        %colors
        MIDIColor                    = [0.5725, 0.2078, 0.7686]

        channel1_sMEGColor           = [0.9882, 0.4745, 0.3451]
        channel1_BloodOxygentionColor= [0.6039, 0.8275, 0.2235]

        channel2_sMEGColor           = [0.9882, 0.4745, 0.3451]
        channel2_BloodOxygentionColor= [0.6039, 0.8275, 0.2235]
    end

    properties(Access = public)
        
        
        debugData1 = [0, 1, 2, 3, 4, 5, 6, 5, 4, 3, 2, 1, 0, 1, 2, 3, 4, 5, 4, 3, 4, 5, 6, 8, 10, 0];
        debugData2 = [100:-1:90];  
        debugData3 = [5, 10, 15, 20, 25, 30, 25, 20, 15, 10, 5, 0];  
        debugData4 = [3, 3, 3, 6, 6, 6, 9, 9, 9, 12, 12, 12, 9, 6, 3, 0]; 
        debugData5 = linspace(0, 1, 26); 
        debugData6 = randi([0, 20], 1, 26);
        

        Data_MIDI;
        Data_Motion;

        Data_Channel1_sEMG;
        Data_Channel2_sEMG;
        Data_Channel1_Blood;
        Data_Channel2_Blood;
        

    end
    
    methods (Access = private)
        
        function result = saveCord(app)
            coords = struct('X', app.markerLocation.X, 'Y', app.markerLocation.Y, 'Z', app.markerLocation.Z);
            jsonStr = jsonencode(coords);
            filename = 'coordinates.json';

            fid = fopen(filename, 'w');
            if fid == -1
                error('Cannot open file %s for writing.', filename);
            end

            fprintf(fid, '%s', jsonStr);
            fclose(fid);

            disp(['Data written to ' filename]);
        end

        function result = saveTolerance(app)
            coords = struct('X', app.markerTolerance.X, 'Y', app.markerTolerance.Y, 'Z', app.markerTolerance.Z);
            jsonStr = jsonencode(coords);
            filename = 'tolerance.json';

            fid = fopen(filename, 'w');
            if fid == -1
                error('Cannot open file %s for writing.', filename);
            end

            fprintf(fid, '%s', jsonStr);
            fclose(fid);

            disp(['Data written to ' filename]);
        end

        function result = loadCord(app)
            filename = 'coordinates.json';
            
            fid = fopen(filename, 'r');
            if fid == -1
                result = false;
                return;
            end
            
            rawData = fread(fid, inf, '*char')';
            fclose(fid);
            
            app.markerLocation = jsondecode(rawData);
           
            % Display the loaded data
            disp('Loaded coordinates:');
            disp(['X: ' num2str(app.markerLocation.X)]);
            disp(['Y: ' num2str(app.markerLocation.Y)]);
            disp(['Z: ' num2str(app.markerLocation.Z)]);
            
            result = true;
            return;
        end

        function result = loadTolerance(app)
            filename = 'tolerance.json';
            
            fid = fopen(filename, 'r');
            if fid == -1
                result = false;
                return;
            end
            
            rawData = fread(fid, inf, '*char')';
            fclose(fid);
            
            app.markerTolerance = jsondecode(rawData);
           
            % Display the loaded data
            disp('Loaded Tolerance:');
            disp(['X: ' num2str(app.markerLocation.X)]);
            disp(['Y: ' num2str(app.markerLocation.Y)]);
            disp(['Z: ' num2str(app.markerLocation.Z)]);
            
            result = true;
            return;
        end

        function reFreshplot(app)
            cla(app.graph);
            hold(app.graph, "on");
            
            legends = [];


            %MIDI
            if (app.setOfPlots(app.plot_options_MIDI.Text))
                plot(app.graph, app.Data_MIDI, "-.", "Color", app.MIDIColor);
                legends = [legends, " MIDI"];
            end

            %Channel 1
            if (app.setOfPlots("channel1_BloodOxygention"))
                plot(app.graph, (app.Data_Channel1_Blood./5).*100, "-.", "Color", app.channel1_BloodOxygentionColor);
                legends = [legends, "Channel1: BloodOxygention in %"];
            end

            if (app.setOfPlots("channel1_sEMG"))
                plot(app.graph, app.Data_Channel1_sEMG, "-.", "Color",app.channel1_sMEGColor);
                legends = [legends, "Channel1: sEMG"];
            end


            %Channel 2
            if (app.setOfPlots("channel2_BloodOxygention"))
                plot(app.graph, (app.Data_Channel2_Blood./5).*100, "Color", app.channel2_BloodOxygentionColor);
                legends = [legends, "Channel2: BloodOxygention in %"];
            end

            if (app.setOfPlots("channel2_sEMG"))
                plot(app.graph, app.Data_Channel2_sEMG, "Color",app.channel2_sMEGColor);
                legends = [legends, "Channel2: sEMG"];
            end
            
            legend(app.graph, legends)
            hold(app.graph, "off");

        end

        function saveData(app) 
            arrays  = {app.Data_Channel1_sEMG, app.Data_Channel2_sEMG, app.Data_Channel1_Blood, app.Data_Channel2_Blood, app.Data_MIDI};
            columnNames = {'sEMG ch1', 'sEMG ch2', 'Blood ch1', 'Blood ch2', 'sync delay'};

            %creating a filename
            currentDateTime = datetime('now', 'Format', 'yyyy-MM-dd_HH-mm-ss');
            fileName = sprintf('output_%s.csv', currentDateTime); 
            %making the file
            fid = fopen(fileName, 'w');
            
            % Write the column headers
            fprintf(fid, '%s,', columnNames{:});
            fprintf(fid, '\n');

            % Find the maximum length of the arrays
            maxLength = max(cellfun(@length, arrays));
            
            % Write each array to the file, row by row
            for i = 1:maxLength
                for j = 1:length(arrays)
                    if i <= length(arrays{j})
                        fprintf(fid, '%g,', arrays{j}(i));
                    else
                        fprintf(fid, ',');
                    end
                end
                fprintf(fid, '\n');
            end

            fclose(fid);


        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            if (app.loadCord() == false)
                app.markerLocation = struct('X', 0, 'Y', 0, 'Z', 0);
            end

            if (app.loadTolerance() == false)
                app.markerTolerance = struct('X', 0, 'Y', 0, 'Z', 0);
            end

            app.MotionCapture_X_EditField.Value = app.markerLocation.X;
            app.MotionCapture_Y_EditField.Value = app.markerLocation.Y;
            app.MotionCapture_Z_EditField.Value = app.markerLocation.Z;

            app.MotionCaptureTol_X_EditField.Value = app.markerTolerance.X;
            app.MotionCaptureTol_Y_EditField.Value = app.markerTolerance.Y;
            app.MotionCaptureTol_Z_EditField.Value = app.markerTolerance.Z;


            app.sEMG_sample_rate = app.sEMG_EditField.Value;
            app.BloodOxygention_sample_rate = app.BloodOxygention_EditField.Value;
            app.MotionCapture_sample_rate = app.MotionCapture_EditField.Value;
            app.base_sample_rate = app.Base_EditField.Value;
            app.frame_size = app.FrameSIze_EditField.Value;


            app.plot_options_MIDI_color.BackgroundColor = app.MIDIColor;
            
            app.plot_options_channel1_BloodOxygention_Color.BackgroundColor = app.channel1_BloodOxygentionColor;
            app.plot_options_channel1_sEMG_color.BackgroundColor = app.channel1_sMEGColor;

            app.plot_options_channel2_BloodOxygention_color.BackgroundColor =  app.channel1_BloodOxygentionColor;
            app.plot_options_channel2_sEMG_color.BackgroundColor =             app.channel2_sMEGColor;

        end

        % Button pushed function: BloodOxygentionSensormatButton
        function BloodOxygentionSensormatButtonPushed(app, event)
            [baseName, folder] = uigetfile(".mat");
            if ischar(baseName) && ischar(folder) && ~isempty(baseName)
                [~, ~, ext] = fileparts(baseName);
                if strcmpi(ext, '.mat')
                    fullFileName = fullfile(folder, baseName);
                    app.BloodOxygentionSensorFile = fullFileName;
                    app.Label_BloodOxygention.Text = baseName;
                else
                    msgbox('The selected file is not a .mat file.', 'Error', 'error');
                end
            end
        end

        % Button pushed function: MIDIcapturewavButton
        function MIDIcapturewavButtonPushed(app, event)
            [baseName, folder] = uigetfile(".wav");
            if ischar(baseName) && ischar(folder) && ~isempty(baseName)
                 [~, ~, ext] = fileparts(baseName);
                 if strcmpi(ext, '.wav')
                    fullFileName = fullfile(folder, baseName);
                    app.SynchronisationSignalFile = fullFileName;
                    app.Label_MIDI.Text = baseName;
                 else
                    msgbox('The selected file is not a .wav file.', 'Error', 'error');
                 end
            end
        end

        % Button pushed function: sEMGsensortxtButton
        function sEMGsensortxtButtonPushed(app, event)
            [baseName, folder] = uigetfile(".txt");
            if ischar(baseName) && ischar(folder) && ~isempty(baseName)
                [~, ~, ext] = fileparts(baseName);
                if strcmpi(ext, '.txt')
                    fullFileName = fullfile(folder, baseName);
                    app.sEMGsensorFile = fullFileName;
                    app.Label_sEMG.Text = app.sEMGsensorFile;
                else
                    msgbox('The selected file is not a .txt file.', 'Error', 'error');
                end
            end
        end

        % Button pushed function: MotionCapturematButton
        function MotionCapturematButtonPushed(app, event)
            [baseName, folder] = uigetfile(".mat");
            if ischar(baseName) && ischar(folder) && ~isempty(baseName)
                [~, ~, ext] = fileparts(baseName);
                if strcmpi(ext, '.mat')
                    fullFileName = fullfile(folder, baseName);
                    app.MotionCaptureFile = fullFileName;
                    app.Label_MotionCapture.Text = baseName;
                else
                    msgbox('The selected file is not a .mat file.', 'Error', 'error');
                end
            end
        end

        % Value changed function: MotionCapture_X_EditField
        function MotionCapture_X_EditFieldValueChanged(app, event)
            value = app.MotionCapture_X_EditField.Value;
            app.markerLocation.X = value;
            app.saveCord();
        end

        % Value changed function: MotionCapture_Y_EditField
        function MotionCapture_Y_EditFieldValueChanged(app, event)
            value = app.MotionCapture_Y_EditField.Value;
            app.markerLocation.Y = value;
            app.saveCord();
        end

        % Value changed function: MotionCapture_Z_EditField
        function MotionCapture_Z_EditFieldValueChanged(app, event)
            value = app.MotionCapture_Z_EditField.Value;
            app.markerLocation.Z = value;
            app.saveCord();
        end

        % Value changed function: MotionCaptureTol_X_EditField
        function MotionCaptureTol_X_EditFieldValueChanged(app, event)
            value = app.MotionCaptureTol_X_EditField.Value;
            app.markerTolerance.X = value;
            app.saveTolerance();
        end

        % Value changed function: MotionCaptureTol_Y_EditField
        function MotionCaptureTol_Y_EditFieldValueChanged(app, event)
            value = app.MotionCaptureTol_Y_EditField.Value;
            app.markerTolerance.Y = value;
            app.saveTolerance();
        end

        % Value changed function: MotionCaptureTol_Z_EditField
        function MotionCaptureTol_Z_EditFieldValueChanged(app, event)
            value = app.MotionCaptureTol_Z_EditField.Value;
            app.markerTolerance.Z = value;
            app.saveTolerance();
        end

        % Selection changed function: SETTINGSButtonGroup
        function SETTINGSButtonGroupSelectionChanged(app, event)

        end

        % Button down function: SETTINGSButtonGroup
        function SETTINGSButtonGroupButtonDown(app, event)
            
        end

        % Button down function: graph
        function graphButtonDown(app, event)
            disp(app.debugData1)
            plot(app.graph, app.debugData1)
        end

        % Value changed function: plot_options_channel1_BloodOxygention
        function plot_options_channel1_BloodOxygentionValueChanged(app, event)
            app.setOfPlots("channel1_BloodOxygention") = ~(app.setOfPlots("channel1_BloodOxygention"));
            app.reFreshplot();
            
        end

        % Button down function: renderTab
        function renderTabButtonDown(app, event)
                
                setOfPlotNames = {app.plot_options_MIDI.Text, 
                    "channel1_BloodOxygention", 
                    "channel2_BloodOxygention", 
                    "channel1_sEMG",
                    "channel2_sEMG"};
                
                app.setOfPlots = configureDictionary("string", "logical");
                
                for i = 1:length(setOfPlotNames)
                    key = setOfPlotNames{i};
                    if ~(app.setOfPlots.isKey(key))
                        app.setOfPlots = insert(app.setOfPlots, key, false);
                    end
                end

                disp(app.setOfPlots)

        end

        % Value changed function: plot_options_MIDI
        function plot_options_MIDIValueChanged(app, event)
            app.setOfPlots(app.plot_options_MIDI.Text) = ~(app.setOfPlots(app.plot_options_MIDI.Text));
            app.reFreshplot();
            
        end

        % Value changed function: plot_options_channel1_sEMG
        function plot_options_channel1_sEMGValueChanged2(app, event)
            app.setOfPlots("channel1_sEMG") = ~(app.setOfPlots("channel1_sEMG"));
            app.reFreshplot();
            
        end

        % Value changed function: plot_options_channel2_BloodOxygention
        function plot_options_channel2_BloodOxygentionValueChanged(app, event)
            app.setOfPlots("channel2_BloodOxygention") = ~(app.setOfPlots("channel2_BloodOxygention"));
            app.reFreshplot();
        end

        % Value changed function: plot_options_channel2_sEMG
        function plot_options_channel2_sEMGValueChanged2(app, event)
            app.setOfPlots("channel2_sEMG") = ~(app.setOfPlots("channel2_sEMG"));
            app.reFreshplot();            
        end

        % Button pushed function: 
        % plot_options_channel1_BloodOxygention_Color
        function plot_options_channel1_BloodOxygention_ColorPushed(app, event)
            c = uisetcolor;
            if ~isequal(c, 0)
            app.plot_options_channel1_BloodOxygention_Color.BackgroundColor = c;
            app.channel1_BloodOxygentionColor = c;
            app.reFreshplot();
            end

        end

        % Button pushed function: plot_options_MIDI_color
        function plot_options_MIDI_colorButtonPushed(app, event)
            c = uisetcolor;
            if ~isequal(c, 0)
                app.plot_options_MIDI_color.BackgroundColor = c;
                app.MIDIColor = c;
                app.reFreshplot();
            end
        end

        % Button pushed function: plot_options_channel1_sEMG_color
        function plot_options_channel1_sEMG_colorButtonPushed2(app, event)
            c = uisetcolor;
            if ~isequal(c, 0)
            app.plot_options_channel1_sEMG_color.BackgroundColor = c;
            app.channel1_sMEGColor = c;
            app.reFreshplot();
            end

        end

        % Button pushed function: 
        % plot_options_channel2_BloodOxygention_color
        function plot_options_channel2_BloodOxygention_colorButtonPushed(app, event)
            c = uisetcolor;
            if ~isequal(c, 0)
            app.plot_options_channel1_BloodOxygention_color.BackgroundColor = c;
            app.channel1_BloodOxygentionColor = c;
            app.reFreshplot();
            end
        end

        % Button pushed function: SynchronizeButton
        function SynchronizeButtonPushed(app, event)
            
            try
                hWaitbar = waitbar(0, 'Please wait, processing...');
                pool = gcp(); % need Parallel Computing Toolbox
                numOutputs = 4;
                future = parfeval(pool, @extractData, numOutputs,...
                    app.SynchronisationSignalFile, ...
                    app.sEMGsensorFile, app.sEMG_sample_rate, ...
                    app.BloodOxygentionSensorFile, app.BloodOxygention_sample_rate, ...
                    app.MotionCaptureFile, app.MotionCapture_sample_rate, [app.markerLocation.X app.markerLocation.Y app.markerLocation.Z], [app.markerTolerance.X app.markerTolerance.Y app.markerTolerance.Z], ...
                    app.base_sample_rate, app.frame_size, 0);
                
                
                barnumber = 0;
                % Keep updating the waitbar until the task is complete
                while ~strcmp(future.State, 'finished')
                    barnumber = barnumber + rand()/100;
                    if barnumber >= 0.99
                        barnumber = 0.99;
                    end
                    waitbar(barnumber, hWaitbar, 'Processing...');
                    pause(0.1); 
                end
                
                % Retrieve the result
                [midi, sEMG, blood, motion] = fetchOutputs(future);
                waitbar(1, hWaitbar, 'Done!');
                pause(0.1);
                close(hWaitbar);
            catch
                try
                    close(hWaitbar)
                catch

                % [midi, sEMG, blood, motion] = extractData(app.SynchronisationSignalFile, ...
                %     app.sEMGsensorFile, app.sEMG_sample_rate, ...
                %     app.BloodOxygentionSensorFile, app.BloodOxygention_sample_rate, ...
                %     app.MotionCaptureFile, app.MotionCapture_sample_rate, [app.markerLocation.X app.markerLocation.Y app.markerLocation.Z], [app.markerTolerance.X app.markerTolerance.Y app.markerTolerance.Z], ...
                %     app.base_sample_rate, app.frame_size, 0);
                app.Data_MIDI = 10;
                app.Data_Channel1_Blood = [1 2 3 4 5]; 
                app.Data_Channel2_Blood = [5 4 3 2 1];
                app.Data_Channel1_sEMG = [6 7 8 9 10];
                app.Data_Channel2_sEMG = [10 9 8 7 6];
                end
            end

            % app.Data_channel1_MIDI = midi.data;
            % app.Data_Channel1_Blood = blood.data(1, :);
            % app.Data_Channel2_Blood = blood.data(2, :);
            % app.Data_Channel1_sEMG = sEMG.data(1, :);
            % app.Data_Channel2_sEMG = sEMG.data(2, :);

            app.Data_MIDI = 10;
                app.Data_Channel1_Blood = [1 2 3 4 5 7 8 9]; 
                app.Data_Channel2_Blood = [5 4 3 2 1 0];
                app.Data_Channel1_sEMG = [6 7 8 9 10];
                app.Data_Channel2_sEMG = [10 9 8 7];

            app.saveData();


        end

        % Value changed function: sEMG_EditField
        function sEMG_EditFieldValueChanged(app, event)

            app.MotionCapture_sample_rate = app.sEMG_EditField.Value;
        end

        % Value changed function: BloodOxygention_EditField
        function BloodOxygention_EditFieldValueChanged(app, event)
            app.BloodOxygention_sample_rate = app.BloodOxygention_EditField.Value;
           
        end

        % Value changed function: MotionCapture_EditField
        function MotionCapture_EditFieldValueChanged(app, event)
            value = app.MotionCapture_EditField.Value;
            app.MotionCapture_sample_rate = value;
        end

        % Value changed function: Base_EditField
        function Base_EditFieldValueChanged(app, event)
            value = app.Base_EditField.Value;
            app.base_sample_rate = value;
        end

        % Value changed function: FrameSIze_EditField
        function FrameSIze_EditFieldValueChanged(app, event)
            value = app.FrameSIze_EditField.Value;
            app.frame_size = value;
        end

        % Button pushed function: plot_options_channel2_sEMG_color
        function plot_options_channel2_sEMG_colorButtonPushed(app, event)
            c = uisetcolor;
            if ~isequal(c, 0)
            app.plot_options_channel1_sEMG_color.BackgroundColor = c;
            app.channel1_sMEGColor = c;
            app.reFreshplot();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.GridLayout2 = uigridlayout(app.Tab);
            app.BloodOxygentionSensormatButton = uibutton(app.GridLayout2, 'push');
            app.BloodOxygentionSensormatButton.ButtonPushedFcn = createCallbackFcn(app, @BloodOxygentionSensormatButtonPushed, true);
            app.BloodOxygentionSensormatButton.Text = {'Blood Oxygention'; 'Sensor (.mat)'};
            app.MIDIcapturewavButton = uibutton(app.GridLayout2, 'push');
            app.MIDIcapturewavButton.ButtonPushedFcn = createCallbackFcn(app, @MIDIcapturewavButtonPushed, true);
            app.MIDIcapturewavButton.Text = 'MIDI capture (.wav)';
            app.sEMGsensortxtButton = uibutton(app.GridLayout2, 'push');
            app.sEMGsensortxtButton.ButtonPushedFcn = createCallbackFcn(app, @sEMGsensortxtButtonPushed, true);
            app.sEMGsensortxtButton.Text = 'sEMG sensor (.txt)';
            app.MotionCapturematButton = uibutton(app.GridLayout2, 'push');
            app.MotionCapturematButton.ButtonPushedFcn = createCallbackFcn(app, @MotionCapturematButtonPushed, true);
            app.MotionCapturematButton.Text = 'Motion Capture (.mat)';
            app.Label_BloodOxygention = uilabel(app.GridLayout2);
            app.Label_BloodOxygention.Text = 'file name';
            app.Label_MIDI = uilabel(app.GridLayout2);
            app.Label_MIDI.Text = 'file name';
            app.Label_sEMG = uilabel(app.GridLayout2);
            app.Label_sEMG.Text = 'file name';
            app.Label_MotionCapture = uilabel(app.GridLayout2);
            app.Label_MotionCapture.Text = 'file name';
            app.SynchronizeButton = uibutton(app.GridLayout2, 'push');
            app.SynchronizeButton.ButtonPushedFcn = createCallbackFcn(app, @SynchronizeButtonPushed, true);
            app.SynchronizeButton.Text = 'Synchronize';
            app.MotionCaptureMarkerCoordinatesPanel = uipanel(app.GridLayout2);
            app.MotionCaptureMarkerCoordinatesPanel.Title = 'Motion Capture Marker Coordinates';
            app.GridLayout = uigridlayout(app.MotionCaptureMarkerCoordinatesPanel);
            app.MotionCapture_X_EditField = uieditfield(app.GridLayout, 'numeric');
            app.MotionCapture_X_EditField.ValueChangedFcn = createCallbackFcn(app, @MotionCapture_X_EditFieldValueChanged, true);
            app.XLabel = uilabel(app.GridLayout);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.GridLayout);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.GridLayout);
            app.ZLabel.Text = 'Z';
            app.MotionCapture_Y_EditField = uieditfield(app.GridLayout, 'numeric');
            app.MotionCapture_Y_EditField.ValueChangedFcn = createCallbackFcn(app, @MotionCapture_Y_EditFieldValueChanged, true);
            app.MotionCapture_Z_EditField = uieditfield(app.GridLayout, 'numeric');
            app.MotionCapture_Z_EditField.ValueChangedFcn = createCallbackFcn(app, @MotionCapture_Z_EditFieldValueChanged, true);
            app.MotionCaptureMarkerTolerancePanel = uipanel(app.GridLayout2);
            app.MotionCaptureMarkerTolerancePanel.Title = 'Motion Capture Marker Tolerance';
            app.GridLayout_2 = uigridlayout(app.MotionCaptureMarkerTolerancePanel);
            app.XLabel_2 = uilabel(app.GridLayout_2);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.GridLayout_2);
            app.YLabel_2.Text = 'Y';
            app.ZLabel_2 = uilabel(app.GridLayout_2);
            app.ZLabel_2.Text = 'Z';
            app.MotionCaptureTol_X_EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.MotionCaptureTol_X_EditField.ValueChangedFcn = createCallbackFcn(app, @MotionCaptureTol_X_EditFieldValueChanged, true);
            app.MotionCaptureTol_Y_EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.MotionCaptureTol_Y_EditField.ValueChangedFcn = createCallbackFcn(app, @MotionCaptureTol_Y_EditFieldValueChanged, true);
            app.MotionCaptureTol_Z_EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.MotionCaptureTol_Z_EditField.ValueChangedFcn = createCallbackFcn(app, @MotionCaptureTol_Z_EditFieldValueChanged, true);
            app.SampleRatesPanel = uipanel(app.GridLayout2);
            app.SampleRatesPanel.Title = 'Sample Rates';
            app.GridLayout_3 = uigridlayout(app.SampleRatesPanel);
            app.sEMG_EditField = uieditfield(app.GridLayout_3, 'numeric');
            app.sEMG_EditField.ValueChangedFcn = createCallbackFcn(app, @sEMG_EditFieldValueChanged, true);
            app.sEMG_EditField.Value = 1000;
            app.sEMG_Label = uilabel(app.GridLayout_3);
            app.sEMG_Label.Text = 'sEMG';
            app.BloodOxygention_Label = uilabel(app.GridLayout_3);
            app.BloodOxygention_Label.Text = 'Blood Oxygention';
            app.MotionCaptureLabel = uilabel(app.GridLayout_3);
            app.MotionCaptureLabel.Text = 'Motion Capture';
            app.BloodOxygention_EditField = uieditfield(app.GridLayout_3, 'numeric');
            app.BloodOxygention_EditField.ValueChangedFcn = createCallbackFcn(app, @BloodOxygention_EditFieldValueChanged, true);
            app.BloodOxygention_EditField.Value = 1000;
            app.MotionCapture_EditField = uieditfield(app.GridLayout_3, 'numeric');
            app.MotionCapture_EditField.ValueChangedFcn = createCallbackFcn(app, @MotionCapture_EditFieldValueChanged, true);
            app.MotionCapture_EditField.Value = 300;
            app.Base_EditField = uieditfield(app.GridLayout_3, 'numeric');
            app.Base_EditField.ValueChangedFcn = createCallbackFcn(app, @Base_EditFieldValueChanged, true);
            app.Base_EditField.Value = 1000;
            app.FrameSIzeLabel = uilabel(app.GridLayout_3);
            app.FrameSIzeLabel.Text = 'Frame SIze';
            app.FrameSIze_EditField = uieditfield(app.GridLayout_3, 'numeric');
            app.FrameSIze_EditField.ValueChangedFcn = createCallbackFcn(app, @FrameSIze_EditFieldValueChanged, true);
            app.FrameSIze_EditField.Value = 5001;
            app.Base_Label = uilabel(app.GridLayout_3);
            app.Base_Label.Text = 'Base';
            app.renderTab = uitab(app.TabGroup);
            app.renderTab.Title = 'render';
            app.renderTab.ButtonDownFcn = createCallbackFcn(app, @renderTabButtonDown, true);
            app.graph = uiaxes(app.renderTab);
            xlabel(app.graph, 'X')
            ylabel(app.graph, 'Y')
            zlabel(app.graph, 'Z')
            app.graph.ButtonDownFcn = createCallbackFcn(app, @graphButtonDown, true);
            app.SETTINGSButtonGroup = uibuttongroup(app.renderTab);
            app.SETTINGSButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SETTINGSButtonGroupSelectionChanged, true);
            app.SETTINGSButtonGroup.Title = 'SETTINGS';
            app.SETTINGSButtonGroup.ButtonDownFcn = createCallbackFcn(app, @SETTINGSButtonGroupButtonDown, true);
            app.plot_options_MIDI = uicheckbox(app.SETTINGSButtonGroup);
            app.plot_options_MIDI.ValueChangedFcn = createCallbackFcn(app, @plot_options_MIDIValueChanged, true);
            app.plot_options_MIDI.Text = 'MIDI';
            app.plot_options_channel1_sEMG = uicheckbox(app.SETTINGSButtonGroup);
            app.plot_options_channel1_sEMG.ValueChangedFcn = createCallbackFcn(app, @plot_options_channel1_sEMGValueChanged2, true);
            app.plot_options_channel1_sEMG.Text = 'sEMG ';
            app.plot_options_channel1_BloodOxygention = uicheckbox(app.SETTINGSButtonGroup);
            app.plot_options_channel1_BloodOxygention.ValueChangedFcn = createCallbackFcn(app, @plot_options_channel1_BloodOxygentionValueChanged, true);
            app.plot_options_channel1_BloodOxygention.Text = 'Blood Oxygention';
            app.Channel2Label = uilabel(app.SETTINGSButtonGroup);
            app.Channel2Label.Text = 'Channel 2';
            app.Channel1Label = uilabel(app.SETTINGSButtonGroup);
            app.Channel1Label.Text = 'Channel 1';
            app.plot_options_channel2_sEMG = uicheckbox(app.SETTINGSButtonGroup);
            app.plot_options_channel2_sEMG.ValueChangedFcn = createCallbackFcn(app, @plot_options_channel2_sEMGValueChanged2, true);
            app.plot_options_channel2_sEMG.Text = 'sEMG ';
            app.plot_options_channel2_BloodOxygention = uicheckbox(app.SETTINGSButtonGroup);
            app.plot_options_channel2_BloodOxygention.ValueChangedFcn = createCallbackFcn(app, @plot_options_channel2_BloodOxygentionValueChanged, true);
            app.plot_options_channel2_BloodOxygention.Text = 'Blood Oxygention';
            app.PLOTLabel = uilabel(app.SETTINGSButtonGroup);
            app.PLOTLabel.Text = 'PLOT';
            app.Plot_options_plot_maxX = uieditfield(app.SETTINGSButtonGroup, 'numeric');
            app.xLabel = uilabel(app.SETTINGSButtonGroup);
            app.xLabel.Text = '≤ x ≤';
            app.Plot_options_plot_minX = uieditfield(app.SETTINGSButtonGroup, 'numeric');
            app.Plot_options_plot_minY = uieditfield(app.SETTINGSButtonGroup, 'numeric');
            app.yLabel = uilabel(app.SETTINGSButtonGroup);
            app.yLabel.Text = '≤ y ≤';
            app.Plot_options_plot_maxY = uieditfield(app.SETTINGSButtonGroup, 'numeric');
            app.Plot_options_plot_grid = uicheckbox(app.SETTINGSButtonGroup);
            app.Plot_options_plot_grid.Text = 'Grid';
            app.Plot_options_plot_grid_2 = uicheckbox(app.SETTINGSButtonGroup);
            app.Plot_options_plot_grid_2.Text = 'Legend';
            app.plot_options_channel2_BloodOxygention_color = uibutton(app.SETTINGSButtonGroup, 'push');
            app.plot_options_channel2_BloodOxygention_color.ButtonPushedFcn = createCallbackFcn(app, @plot_options_channel2_BloodOxygention_colorButtonPushed, true);
            app.plot_options_channel2_BloodOxygention_color.Text = '';
            app.plot_options_channel2_sEMG_color = uibutton(app.SETTINGSButtonGroup, 'push');
            app.plot_options_channel2_sEMG_color.ButtonPushedFcn = createCallbackFcn(app, @plot_options_channel2_sEMG_colorButtonPushed, true);
            app.plot_options_channel2_sEMG_color.Text = '';
            app.plot_options_channel1_BloodOxygention_Color = uibutton(app.SETTINGSButtonGroup, 'push');
            app.plot_options_channel1_BloodOxygention_Color.ButtonPushedFcn = createCallbackFcn(app, @plot_options_channel1_BloodOxygention_ColorPushed, true);
            app.plot_options_channel1_BloodOxygention_Color.Text = '';
            app.plot_options_MIDI_color = uibutton(app.SETTINGSButtonGroup, 'push');
            app.plot_options_MIDI_color.ButtonPushedFcn = createCallbackFcn(app, @plot_options_MIDI_colorButtonPushed, true);
            app.plot_options_MIDI_color.Text = '';
            app.plot_options_channel1_sEMG_color = uibutton(app.SETTINGSButtonGroup, 'push');
            app.plot_options_channel1_sEMG_color.ButtonPushedFcn = createCallbackFcn(app, @plot_options_channel1_sEMG_colorButtonPushed2, true);
            app.plot_options_channel1_sEMG_color.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = piano

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end